classdef EBM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        USRA_EBM                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        RCPsTab                   matlab.ui.container.Tab
        UIAxes                    matlab.ui.control.UIAxes
        ClimatePathwayPanel       matlab.ui.container.Panel
        RCP26CheckBox             matlab.ui.control.CheckBox
        RCP60CheckBox             matlab.ui.control.CheckBox
        RCP45CheckBox             matlab.ui.control.CheckBox
        RCP85CheckBox             matlab.ui.control.CheckBox
        clearplotButton           matlab.ui.control.Button
        GenerateButton            matlab.ui.control.Button
        Switch                    matlab.ui.control.Switch
        Label                     matlab.ui.control.Label
        StartYearSpinnerLabel     matlab.ui.control.Label
        StartYearSpinner          matlab.ui.control.Spinner
        EndYearSpinnerLabel       matlab.ui.control.Label
        EndYearSpinner            matlab.ui.control.Spinner
        ForcingsTab               matlab.ui.container.Tab
        ForcingsPanel             matlab.ui.container.Panel
        FO                        matlab.ui.control.NumericEditField
        QWm2EditField_2Label      matlab.ui.control.Label
        FA                        matlab.ui.control.NumericEditField
        Q                         matlab.ui.control.NumericEditField
        deltaEditField_2Label     matlab.ui.control.Label
        delta                     matlab.ui.control.NumericEditField
        FOWm2DropDownLabel        matlab.ui.control.Label
        FODropDown                matlab.ui.control.DropDown
        FAWm2DropDownLabel        matlab.ui.control.Label
        FADropDown                matlab.ui.control.DropDown
        FOValueLabel              matlab.ui.control.Label
        FAValueLabel              matlab.ui.control.Label
        HRpermafrostrelatedPanel  matlab.ui.container.Panel
        Q10EditFieldLabel         matlab.ui.control.Label
        Q10                       matlab.ui.control.NumericEditField
        LabelonplotCheckBox       matlab.ui.control.CheckBox
        CalculatorPanel           matlab.ui.container.Panel
        BiasButtonGroup           matlab.ui.container.ButtonGroup
        ColdButton                matlab.ui.control.RadioButton
        TemperateButton           matlab.ui.control.RadioButton
        WarmButton                matlab.ui.control.RadioButton
        GHGsPanel                 matlab.ui.container.Panel
        muppmEditFieldLabel       matlab.ui.control.Label
        mu                        matlab.ui.control.NumericEditField
        nuppbEditFieldLabel       matlab.ui.control.Label
        nu                        matlab.ui.control.NumericEditField
        tau_sEditFieldLabel       matlab.ui.control.Label
        tau_s                     matlab.ui.control.NumericEditField
        Switch_2                  matlab.ui.control.Switch
        CalculateButton           matlab.ui.control.Button
    end

    
    properties (Access = public)
        FO_End                    matlab.ui.control.NumericEditField
        FA_End                    matlab.ui.control.NumericEditField
        FOValueLabelEnd              matlab.ui.control.Label
        FAValueLabelEnd              matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            clear r; 
            r = rcpsim(app.FO.Value, app.FA.Value, app.Q.Value, app.delta.Value);
            
            %set params 
            Start = app.StartYearSpinner.Value;
            End = app.EndYearSpinner.Value;
            times = linspace(Start, End, End - Start + 1);
           
            if app.FODropDown.Value == "Linear"
                linearFO = @(x) app.FO.Value + (x - Start).*(app.FO_End.Value - app.FO.Value)./(End - Start);
                for i = 1:length(times)
                    FO_arr(i) = linearFO(times(i));
                end
                r.FO_arr = FO_arr;    
            end
            
            if app.FADropDown.Value == "Linear"
                linearFA = @(x) app.FA.Value + (x - Start).*(app.FA_End.Value - app.FA.Value)./(End - Start);
                for i = 1:length(times)
                    FA_arr(i) = linearFA(times(i));
                end
                r.FA_arr = FA_arr;    
            end
            
            r.ebm.Q_10 = app.Q10.Value;
            
            pathways = [];
            if app.RCP26CheckBox.Value 
                pathways = [pathways "2.6"];
            end
            if app.RCP45CheckBox.Value 
                pathways = [pathways "4.5"];
            end
            if app.RCP60CheckBox.Value 
                pathways = [pathways "6.0"];
            end
            if app.RCP85CheckBox.Value 
                pathways = [pathways "8.5"];
            end
            
            xlim(app.UIAxes, [Start End]);
            yticks(app.UIAxes, 'auto');
            
            %sequential plotting
            if app.Switch.Value == "No Permafrost"
                for i = 1:length(pathways)
                    clear t 
                    t = RCPNoPermafrost(r, pathways(i), [app.StartYearSpinner.Value app.EndYearSpinner.Value]);
                    plot(app.UIAxes, times, t, 'DisplayName', "RCP " + pathways(i));
                    hold(app.UIAxes, "on") 
                end 
            else 
                %for display purposes
                if app.LabelonplotCheckBox.Value
                    suffix = "(P, " + "Q10 = " + num2str(r.ebm.Q_10) + ")";
                else 
                    suffix = "(P)";
                end
                    
                for i = 1:length(pathways)
                    clear t 
                    t = RCPrk3ab3(r, pathways(i), [app.StartYearSpinner.Value app.EndYearSpinner.Value]);
                    plot(app.UIAxes, times, t, 'DisplayName', "RCP " + pathways(i)+ " " + suffix);
                    hold(app.UIAxes, "on")
                end 
            end 
            lgd = legend(app.UIAxes,'Location','northwest');
            lgd.FontSize = 8;
            %title(app.UIAxes, "RCP: " + strjoin(pathways,', ')) 
        end

        % Button pushed function: clearplotButton
        function clearplotButtonPushed(app, event)
            cla(app.UIAxes);
            legend(app.UIAxes, "off");
        end

        % Value changed function: FODropDown
        function FODropDownValueChanged(app, event)
            value = app.FODropDown.Value;
            if value == "Fixed"
                if isprop(app,'FO_End')
                    delete(app.FO_End)
                    delete(app.FOValueLabelEnd)
                end
                app.FOValueLabel.Text = "Value:";
                app.FO.Position = [242,157,84,22];
            end
            if value == "Linear"
                app.FO.Position = [242,157,42,22]
                app.FOValueLabel.Text = "Start:";
                %add end numeric field and label
                app.FO_End = uieditfield(app.ForcingsPanel, 'numeric');
                app.FO_End.Position = [329 157 41 22];
                app.FOValueLabelEnd = uilabel(app.ForcingsPanel);
                app.FOValueLabelEnd.Position = [298,157,38,22];
                app.FOValueLabelEnd.Text = 'End:';
                app.FO_End.Value = 19.5;
            end
        end

        % Value changed function: FADropDown
        function FADropDownValueChanged(app, event)
            value = app.FADropDown.Value;
            if value == "Fixed"
                if isprop(app,'FA_End')
                    delete(app.FA_End)
                    delete(app.FAValueLabelEnd)
                end
                app.FAValueLabel.Text = "Value:";
                app.FA.Position = [242,112,87,22];
            end
            if value == "Linear"
                app.FA.Position = [242,112,42,22];
                app.FAValueLabel.Text = "Start:";
                %add end numeric field and label
                app.FA_End = uieditfield(app.ForcingsPanel, 'numeric');
                app.FA_End.Position = [329 112 41 22];
                app.FAValueLabelEnd = uilabel(app.ForcingsPanel);
                app.FAValueLabelEnd.Position = [298,112,38,22];
                app.FAValueLabelEnd.Text = 'End:';
                app.FA_End.Value = 97.53;
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            e = ebm(app.FO.Value, app.FA.Value, app.Q.Value, app.delta.Value);
            e.mu = app.mu.Value;
            e.nu = app.nu.Value;
            if app.Switch_2.Value == "Celcius"
                if app.ColdButton.Value
                    eqtemp(e,0.9);
                    app.tau_s.Value = e.tau_s.*273.15 - 273.15;
                elseif app.TemperateButton.Value
                    eqtemp(e,1);
                    app.tau_s.Value = e.tau_s.*273.15 - 273.15;
                elseif app.WarmButton.Value
                    eqtemp(e,1.03);
                    app.tau_s.Value = e.tau_s.*273.15 - 273.15;   
                end 
            else
                 if app.ColdButton.Value
                    eqtemp(e,0.9);
                    app.tau_s.Value = e.tau_s;
                elseif app.TemperateButton.Value
                    eqtemp(e,1);
                    app.tau_s.Value = e.tau_s;
                elseif app.WarmButton.Value
                    eqtemp(e,1.03);
                    app.tau_s.Value = e.tau_s;   
                 end 
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.USRA_EBM = uifigure('Visible', 'off');
            app.USRA_EBM.Name = 'EBM';
            app.TabGroup = uitabgroup(app.USRA_EBM);
            app.RCPsTab = uitab(app.TabGroup);
            app.RCPsTab.Title = 'RCP''s';
            app.UIAxes = uiaxes(app.RCPsTab);
            title(app.UIAxes, {''; ''})
            xlabel(app.UIAxes, 'Year')
            ylabel(app.UIAxes, 'tau_s')
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.ClimatePathwayPanel = uipanel(app.RCPsTab);
            app.ClimatePathwayPanel.Title = 'Climate Pathway';
            app.RCP26CheckBox = uicheckbox(app.ClimatePathwayPanel);
            app.RCP26CheckBox.Text = 'RCP 2.6';
            app.RCP60CheckBox = uicheckbox(app.ClimatePathwayPanel);
            app.RCP60CheckBox.Text = 'RCP 6.0';
            app.RCP45CheckBox = uicheckbox(app.ClimatePathwayPanel);
            app.RCP45CheckBox.Text = 'RCP 4.5';
            app.RCP85CheckBox = uicheckbox(app.ClimatePathwayPanel);
            app.RCP85CheckBox.Text = 'RCP 8.5';
            app.clearplotButton = uibutton(app.RCPsTab, 'push');
            app.clearplotButton.ButtonPushedFcn = createCallbackFcn(app, @clearplotButtonPushed, true);
            app.clearplotButton.Text = {'clear plot'; ''};
            app.GenerateButton = uibutton(app.RCPsTab, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.Switch = uiswitch(app.RCPsTab, 'slider');
            app.Switch.Items = {'No Permafrost', 'Permafrost'};
            app.Switch.Value = 'No Permafrost';
            app.Label = uilabel(app.RCPsTab);
            app.Label.Text = '';
            app.StartYearSpinnerLabel = uilabel(app.RCPsTab);
            app.StartYearSpinnerLabel.Text = 'Start Year';
            app.StartYearSpinner = uispinner(app.RCPsTab);
            app.StartYearSpinner.Limits = [1765 2500];
            app.StartYearSpinner.Value = 1900;
            app.EndYearSpinnerLabel = uilabel(app.RCPsTab);
            app.EndYearSpinnerLabel.Text = 'End Year';
            app.EndYearSpinner = uispinner(app.RCPsTab);
            app.EndYearSpinner.Limits = [1765 2500];
            app.EndYearSpinner.Value = 2300;
            app.ForcingsTab = uitab(app.TabGroup);
            app.ForcingsTab.Title = 'Forcings';
            app.ForcingsPanel = uipanel(app.ForcingsTab);
            app.ForcingsPanel.Title = 'Forcings';
            app.FO = uieditfield(app.ForcingsPanel, 'numeric');
            app.FO.Value = 9.75;
            app.QWm2EditField_2Label = uilabel(app.ForcingsPanel);
            app.QWm2EditField_2Label.Text = 'Q [Wm^-2]';
            app.FA = uieditfield(app.ForcingsPanel, 'numeric');
            app.FA.Value = 104;
            app.Q = uieditfield(app.ForcingsPanel, 'numeric');
            app.Q.Value = 201.73;
            app.deltaEditField_2Label = uilabel(app.ForcingsPanel);
            app.deltaEditField_2Label.Text = 'delta [ ]';
            app.delta = uieditfield(app.ForcingsPanel, 'numeric');
            app.delta.Value = 0.6;
            app.FOWm2DropDownLabel = uilabel(app.ForcingsPanel);
            app.FOWm2DropDownLabel.Text = 'FO [Wm^-2]';
            app.FODropDown = uidropdown(app.ForcingsPanel);
            app.FODropDown.Items = {'Fixed', 'Linear', ''};
            app.FODropDown.ValueChangedFcn = createCallbackFcn(app, @FODropDownValueChanged, true);
            app.FODropDown.Value = 'Fixed';
            app.FAWm2DropDownLabel = uilabel(app.ForcingsPanel);
            app.FAWm2DropDownLabel.Text = 'FA [Wm^-2]';
            app.FADropDown = uidropdown(app.ForcingsPanel);
            app.FADropDown.Items = {'Fixed', 'Linear', ''};
            app.FADropDown.ValueChangedFcn = createCallbackFcn(app, @FADropDownValueChanged, true);
            app.FADropDown.Value = 'Fixed';
            app.FOValueLabel = uilabel(app.ForcingsPanel);
            app.FOValueLabel.Text = 'Value:';
            app.FAValueLabel = uilabel(app.ForcingsPanel);
            app.FAValueLabel.Text = 'Value:';
            app.HRpermafrostrelatedPanel = uipanel(app.ForcingsTab);
            app.HRpermafrostrelatedPanel.Title = 'HR (permafrost-related)';
            app.Q10EditFieldLabel = uilabel(app.HRpermafrostrelatedPanel);
            app.Q10EditFieldLabel.Text = 'Q10';
            app.Q10 = uieditfield(app.HRpermafrostrelatedPanel, 'numeric');
            app.Q10.Limits = [0.01 Inf];
            app.Q10.Value = 1.5;
            app.LabelonplotCheckBox = uicheckbox(app.HRpermafrostrelatedPanel);
            app.LabelonplotCheckBox.Text = 'Label on plot';
            app.CalculatorPanel = uipanel(app.ForcingsTab);
            app.CalculatorPanel.Title = 'Calculator';
            app.BiasButtonGroup = uibuttongroup(app.CalculatorPanel);
            app.BiasButtonGroup.Title = 'Bias';
            app.ColdButton = uiradiobutton(app.BiasButtonGroup);
            app.ColdButton.Text = 'Cold';
            app.TemperateButton = uiradiobutton(app.BiasButtonGroup);
            app.TemperateButton.Text = 'Temperate';
            app.TemperateButton.Value = true;
            app.WarmButton = uiradiobutton(app.BiasButtonGroup);
            app.WarmButton.Text = 'Warm';
            app.GHGsPanel = uipanel(app.CalculatorPanel);
            app.GHGsPanel.Title = 'GHG''s';
            app.muppmEditFieldLabel = uilabel(app.GHGsPanel);
            app.muppmEditFieldLabel.Text = 'mu [ppm]';
            app.mu = uieditfield(app.GHGsPanel, 'numeric');
            app.mu.Value = 407.4;
            app.nuppbEditFieldLabel = uilabel(app.GHGsPanel);
            app.nuppbEditFieldLabel.Text = 'nu [ppb]';
            app.nu = uieditfield(app.GHGsPanel, 'numeric');
            app.nu.Value = 1875;
            app.tau_sEditFieldLabel = uilabel(app.CalculatorPanel);
            app.tau_sEditFieldLabel.Text = 'tau_s';
            app.tau_s = uieditfield(app.CalculatorPanel, 'numeric');
            app.Switch_2 = uiswitch(app.CalculatorPanel, 'slider');
            app.Switch_2.Items = {'Nondimensional', 'Celcius'};
            app.Switch_2.Value = 'Nondimensional';
            app.CalculateButton = uibutton(app.CalculatorPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            % Show the figure after all components are created
            app.USRA_EBM.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EBM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.USRA_EBM)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.USRA_EBM)
        end
    end
end